classdef DataCollectionModule2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        UserInformationPanel          matlab.ui.container.Panel
        FirstNameEditField_2Label     matlab.ui.control.Label
        FirstNameEditField_2          matlab.ui.control.EditField
        LastNameEditField_2Label      matlab.ui.control.Label
        LastNameEditField_2           matlab.ui.control.EditField
        IDEditFieldLabel              matlab.ui.control.Label
        IDEditField                   matlab.ui.control.EditField
        PatientInformationPanel       matlab.ui.container.Panel
        FirstNameEditFieldLabel       matlab.ui.control.Label
        FirstNameEditField            matlab.ui.control.EditField
        LastNameEditFieldLabel        matlab.ui.control.Label
        LastNameEditField             matlab.ui.control.EditField
        DOBEditFieldLabel             matlab.ui.control.Label
        DOBEditField                  matlab.ui.control.EditField
        MRNEditFieldLabel             matlab.ui.control.Label
        MRNEditField                  matlab.ui.control.EditField
        SexEditFieldLabel             matlab.ui.control.Label
        SexEditField                  matlab.ui.control.EditField
        AgeEditFieldLabel             matlab.ui.control.Label
        AgeEditField                  matlab.ui.control.EditField
        WeightEditFieldLabel          matlab.ui.control.Label
        WeightEditField               matlab.ui.control.EditField
        Field1EditFieldLabel          matlab.ui.control.Label
        Field1EditField               matlab.ui.control.EditField
        Field2EditFieldLabel          matlab.ui.control.Label
        Field2EditField               matlab.ui.control.EditField
        Field3EditFieldLabel          matlab.ui.control.Label
        Field3EditField               matlab.ui.control.EditField
        UpdateSleepStatusButtonGroup  matlab.ui.container.ButtonGroup
        ActiveButton                  matlab.ui.control.ToggleButton
        QuietButton                   matlab.ui.control.ToggleButton
        TextArea                      matlab.ui.control.TextArea
        UploadRIPDataFileButton       matlab.ui.control.Button
        LiveTestPanel                 matlab.ui.container.Panel
        StartTestButton               matlab.ui.control.Button
        ElapsedTimeEditFieldLabel     matlab.ui.control.Label
        ElapsedTimeEditField          matlab.ui.control.EditField
    end

    
    properties (Access = private)
        StartTime = 0;
        Timer
        SleepStatusChange = [0];
        PTInfo
        UserInfo
    end
    
    methods (Access = private)
        
        function setUpTimer(app)
            app.Timer = timer('Period',0.1,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            app.Timer.TimerFcn = @(timerObj, event)updateETimeDisp(app, timerObj, event);
            app.Timer.StartFcn = @(timerObj, event)saveStartTime(app, timerObj, event);
            app.Timer.StopFcn = @(timerObj, event)updateSleepStatus(app, timerObj, event);
            
        end
        
        % TIMER RUN FUNCTION
        function updateETimeDisp(app, timerObj, event)
           elapsed = etime(event.Data.time, app.StartTime);
           app.ElapsedTimeEditField.Value = num2str(elapsed);
            
        end
        
        % TIMER START FUNCTION
        function saveStartTime(app, timerObj, event)
            app.StartTime = event.Data.time;
        end
        
        % TIMER STOP FUNCTION
        function updateSleepStatus(app, timerObj, event)
            elapsed = etime(clock, app.StartTime);
            app.SleepStatusChange = [app.SleepStatusChange; {elapsed}];
        end
        
        
        function goodToClose = checkPTInfo(app)
        % Assembles and checks if Patient Info contains any identifying information. If
        % it does, ensure that user information is also provided. Warn if
        % no patient information is provided. 
        
            app.PTInfo.FirstName = app.FirstNameEditField.Value;
            app.PTInfo.LastName = app.LastNameEditField.Value;
            app.PTInfo.DOB = app.DOBEditField.Value;
            app.PTInfo.MRN = app.MRNEditField.Value;
            app.PTInfo.Sex = app.SexEditField.Value;
            app.PTInfo.Age = app.AgeEditField.Value;
            app.PTInfo.Weight = app.WeightEditField.Value;
            app.PTInfo.Field1 = app.Field1EditField.Value;
            app.PTInfo.Field2 = app.Field2EditField.Value;
            app.PTInfo.Field3 = app.Field3EditField.Value;
            
            app.UserInfo.FirstName = app.FirstNameEditField_2.Value;
            app.UserInfo.LastName = app.LastNameEditField_2.Value;
            app.UserInfo.ID = app.IDEditField.Value;
            
            isEmpty = structfun(@isempty, app.PTInfo);
            isNotEmpty = ~isEmpty;
            if any(isNotEmpty)
                if isempty(app.UserInfo.FirstName) || isempty(app.UserInfo.LastName)
                    uialert(app.UIFigure, "This record contains confidential patient information. Ensure the first and last name of the medical professional responsible for data security are entered in the User Information section before uploading.");
                    goodToClose = false;
                else
                    goodToClose = true;
                end
            else
                selection = uiconfirm(app.UIFigure,...
                    "This record will contain no identifying information. Are you sure you want to proceed?", ...
                    "No Patient Information",...
                    'Options', {'Continue', 'Go Back'}, 'DefaultOption', 2, 'CancelOption', 2);
                if strcmp(selection, 'Continue')
                    goodToClose = true;
                else
                    goodToClose = false;
                end
            end
        end
        
        function goodToClose = checkSleepStatus(app)
        % Check if any sleep status information has been provided via the
        % toggle buttons. If not, check with user to make sure this is what
        % they intended. 
        
            if isempty(app.SleepStatusChange)
                selection = uiconfirm(app.UIFigure,...
                    "Are you sure you wish to upload a recording without entering sleep status? The entire duration will be considered viable for analysis.", ...
                    "No Sleep Status",...
                    'Options', {'Continue', 'Go Back'}, 'DefaultOption', 2, 'CancelOption', 2);
                if strcmp(selection, 'Continue')
                    goodToClose = true;
                else
                    goodToClose = false;
                end
            else
                goodToClose = true;
            end
            
        end
        
        function runAlgorithm(app, CHBandRecording)
            matcher = Matcher(CHBandRecording);
            CHBandRecording = matcher.alignBreaths();
            
            sorter = Sorter(CHBandRecording);
            CHBandRecording = sorter.sortBreaths();
            
            analyzer = Analyzer(CHBandRecording);
            CHBandRecording = analyzer.calculateParameters();
            
            analysisApp = analysisScreen2(CHBandRecording);
            
        end
        
        function autofillPTUserInfo(app)
            % PT Info
            app.FirstNameEditField.Value = 'John';
            app.LastNameEditField.Value = 'Doe';
            app.DOBEditField.Value = '01/01/1990';
            app.MRNEditField.Value = '000123456';
            
            % User Info
            app.FirstNameEditField_2.Value = 'Jane';
            app.LastNameEditField_2.Value = 'Smith';
            app.IDEditField.Value = '00036912';
            
        end
    end
    
    methods (Access = public)
        
        function results = getSleepStatusChange(app)
            results = app.SleepStatusChange;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % FOR DEMO
            app.autofillPTUserInfo();
            %
            
            app.setUpTimer();
            
        end

        % Button pushed function: StartTestButton
        function StartTestButtonPushed(app, event)
            state = get(app.Timer,'Running');
            if strcmp(state, 'off')
                start(app.Timer);
                app.StartTestButton.Text = 'Stop Test';
                app.StartTestButton.BackgroundColor = [1 0.5882 0.5882];
            elseif strcmp(state, 'on')
                app.StartTestButton.Enable = false;
                stop(app.Timer);
                elapsed = etime(clock, app.StartTime);
                app.SleepStatusChange = [app.SleepStatusChange; {elapsed}];
            end
   
                
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.Timer);
            delete(app);
            
        end

        % Selection changed function: UpdateSleepStatusButtonGroup
        function UpdateSleepStatusButtonGroupSelectionChanged(app, event)

            elapsed = etime(clock, app.StartTime);
            app.SleepStatusChange = [app.SleepStatusChange; {elapsed}];
            
        end

        % Button pushed function: UploadRIPDataFileButton
        function UploadRIPDataFileButtonPushed(app, event)

            state = get(app.Timer,'Running');
            if strcmp(state, 'on')
                stop(app.Timer);
            end
            
            PTInfoCheck = app.checkPTInfo();
            if PTInfoCheck
                sleepStatusCheck = app.checkSleepStatus();
                if sleepStatusCheck
                    fileInHandler = FileInHandler(app.UIFigure, app.SleepStatusChange, app.PTInfo, app.UserInfo);
                    [goodToClose, CHrecording] = fileInHandler.uploadRawDataFile();
                    if goodToClose
                        app.runAlgorithm(CHrecording);
                        delete(app.Timer);
                        delete(app);
                    end
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UserInformationPanel = uipanel(app.GridLayout);
            app.UserInformationPanel.Title = 'User Information';
            app.FirstNameEditField_2Label = uilabel(app.UserInformationPanel);
            app.FirstNameEditField_2Label.Text = 'First Name';
            app.FirstNameEditField_2 = uieditfield(app.UserInformationPanel, 'text');
            app.LastNameEditField_2Label = uilabel(app.UserInformationPanel);
            app.LastNameEditField_2Label.Text = 'Last Name';
            app.LastNameEditField_2 = uieditfield(app.UserInformationPanel, 'text');
            app.IDEditFieldLabel = uilabel(app.UserInformationPanel);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.UserInformationPanel, 'text');
            app.PatientInformationPanel = uipanel(app.GridLayout);
            app.PatientInformationPanel.Title = 'Patient Information';
            app.FirstNameEditFieldLabel = uilabel(app.PatientInformationPanel);
            app.FirstNameEditFieldLabel.Text = 'First Name';
            app.FirstNameEditField = uieditfield(app.PatientInformationPanel, 'text');
            app.LastNameEditFieldLabel = uilabel(app.PatientInformationPanel);
            app.LastNameEditFieldLabel.Text = 'Last Name';
            app.LastNameEditField = uieditfield(app.PatientInformationPanel, 'text');
            app.DOBEditFieldLabel = uilabel(app.PatientInformationPanel);
            app.DOBEditFieldLabel.Text = 'DOB';
            app.DOBEditField = uieditfield(app.PatientInformationPanel, 'text');
            app.MRNEditFieldLabel = uilabel(app.PatientInformationPanel);
            app.MRNEditFieldLabel.Text = 'MRN';
            app.MRNEditField = uieditfield(app.PatientInformationPanel, 'text');
            app.SexEditFieldLabel = uilabel(app.PatientInformationPanel);
            app.SexEditFieldLabel.Text = 'Sex';
            app.SexEditField = uieditfield(app.PatientInformationPanel, 'text');
            app.AgeEditFieldLabel = uilabel(app.PatientInformationPanel);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.PatientInformationPanel, 'text');
            app.WeightEditFieldLabel = uilabel(app.PatientInformationPanel);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.WeightEditField = uieditfield(app.PatientInformationPanel, 'text');
            app.Field1EditFieldLabel = uilabel(app.PatientInformationPanel);
            app.Field1EditFieldLabel.Text = 'Field 1';
            app.Field1EditField = uieditfield(app.PatientInformationPanel, 'text');
            app.Field2EditFieldLabel = uilabel(app.PatientInformationPanel);
            app.Field2EditFieldLabel.Text = 'Field 2';
            app.Field2EditField = uieditfield(app.PatientInformationPanel, 'text');
            app.Field3EditFieldLabel = uilabel(app.PatientInformationPanel);
            app.Field3EditFieldLabel.Text = 'Field 3';
            app.Field3EditField = uieditfield(app.PatientInformationPanel, 'text');
            app.UpdateSleepStatusButtonGroup = uibuttongroup(app.GridLayout);
            app.UpdateSleepStatusButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UpdateSleepStatusButtonGroupSelectionChanged, true);
            app.UpdateSleepStatusButtonGroup.Title = 'Update Sleep Status';
            app.ActiveButton = uitogglebutton(app.UpdateSleepStatusButtonGroup);
            app.ActiveButton.Text = 'Active';
            app.ActiveButton.Value = true;
            app.QuietButton = uitogglebutton(app.UpdateSleepStatusButtonGroup);
            app.QuietButton.Text = 'Quiet';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'To update sleep status during recording:'; '1. Click Start Test when data begins streaming to BioCapture.'; '2. Toggle sleep status as data is collected. Note that the default/starting sleep status is ACTIVE (non-analyzable).'; '3. Click Stop Test when finished recording.'; '4. Upload .csv export of BioCapture recording.'};
            app.UploadRIPDataFileButton = uibutton(app.GridLayout, 'push');
            app.UploadRIPDataFileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadRIPDataFileButtonPushed, true);
            app.UploadRIPDataFileButton.Text = 'Upload RIP Data File';
            app.LiveTestPanel = uipanel(app.GridLayout);
            app.LiveTestPanel.Title = 'Live Test';
            app.StartTestButton = uibutton(app.LiveTestPanel, 'push');
            app.StartTestButton.ButtonPushedFcn = createCallbackFcn(app, @StartTestButtonPushed, true);
            app.StartTestButton.Text = 'Start Test';
            app.ElapsedTimeEditFieldLabel = uilabel(app.LiveTestPanel);
            app.ElapsedTimeEditFieldLabel.Text = 'Elapsed Time';
            app.ElapsedTimeEditField = uieditfield(app.LiveTestPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataCollectionModule2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end