classdef analysisScreen2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        AxisControlsPanel               matlab.ui.container.Panel
        GoToEditFieldLabel              matlab.ui.control.Label
        GoToEditField                   matlab.ui.control.NumericEditField
        DisplayEditFieldLabel           matlab.ui.control.Label
        DisplayEditField                matlab.ui.control.NumericEditField
        ResetAxesButton                 matlab.ui.control.Button
        secondsofdataLabel              matlab.ui.control.Label
        secondsLabel                    matlab.ui.control.Label
        PerBreathAnalysisPanel          matlab.ui.container.Panel
        ExcludeBreathButton             matlab.ui.control.Button
        PALabel                         matlab.ui.control.Label
        TPEFTELabel                     matlab.ui.control.Label
        RCiLabel                        matlab.ui.control.Label
        ViewKMPlotButton                matlab.ui.control.Button
        DurationLabel                   matlab.ui.control.Label
        ResetAnalysisButton             matlab.ui.control.Button
        ViewAnalysisDetailsButton       matlab.ui.control.Button
        ExportAnalysisButton            matlab.ui.control.Button
        SummaryofRecordingPanel         matlab.ui.container.Panel
        MeanPALabel                     matlab.ui.control.Label
        TotalNumberofBreathsLabel       matlab.ui.control.Label
        MeanTPEFTELabel                 matlab.ui.control.Label
        MeanRCiLabel                    matlab.ui.control.Label
        NumberofAnalyzableBreathsLabel  matlab.ui.control.Label
        MeanDurationLabel               matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        SelectBreathPanel               matlab.ui.container.Panel
        BreathEditFieldLabel            matlab.ui.control.Label
        BreathEditField                 matlab.ui.control.NumericEditField
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        PreviousNextAnalyzableBreathLabel  matlab.ui.control.Label
        ExportRawDataButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        OriginalCHData
        CHData
        Metadata
        
        CHBreathLim
        ABBreathLim
        
        CurrentCHPlot
        CurrentABPlot
        
        RemovedBreaths = [];
        
        CurrentBreath = 0;
        
        Width = 30;

    end
    
    methods (Access = private)
        
        function plotBreaths(app)
            app.plotCHbreaths();
            app.plotABbreaths();
            app.resetAxes();
        end
            
        function plotCHbreaths(app)

            hold(app.UIAxes, 'on');
            
            waveform = app.CHData.getFiltData();
            timeVector = (1:length(waveform))./app.Metadata.SamplingFrequency;
            waveform = downsample(waveform, 20);
            timeVector = downsample(timeVector, 20);
            
            plot(app.UIAxes, timeVector, waveform, 'Color', '#A9A9A9');
            
            analyzableBreaths = app.CHData.getIndicesAnalyzable();
            for i = 1:length(analyzableBreaths)
                CHbreath = app.CHData.getBreath(analyzableBreaths(i));
                CHdata = CHbreath.getData();
                indices = CHbreath.getIndices();
                samplesPerSec = 10;
                nthSample = floor(app.Metadata.SamplingFrequency/samplesPerSec);
                
                Ydata = downsample(CHdata,nthSample);
                Xdata = downsample(indices/app.Metadata.SamplingFrequency, nthSample);
              
                plot(app.UIAxes, Xdata, Ydata, ...
                    'Color', '#00ff00', 'LineWidth', 2); 
                
                label = int2str(i);
                xline(app.UIAxes, Xdata(1), ':', label);
            end
            

            hold(app.UIAxes, 'off');
        end
        
        function plotABbreaths(app)

            hold(app.UIAxes2, 'on');
            
            waveform = app.CHData.getAssociatedData();
            timeVector = (1:length(waveform))./app.Metadata.SamplingFrequency;
            
            BGsamplesPerSec = 10;
            nthSampleBG = floor(app.Metadata.SamplingFrequency/BGsamplesPerSec);
            
            timeVectorDS = downsample(timeVector, nthSampleBG);
            waveformDS = downsample(waveform, nthSampleBG);
            
            plot(app.UIAxes2, timeVectorDS, waveformDS, 'Color', '#A9A9A9');
            
            analyzableBreaths = app.CHData.getIndicesAnalyzable();
            for i = 1:length(analyzableBreaths)
                CHbreath = app.CHData.getBreath(analyzableBreaths(i));
                ABdata = CHbreath.getAssociatedData();
                indices = CHbreath.getIndices();
                samplesPerSec = 10;
                nthSample = floor(app.Metadata.SamplingFrequency/samplesPerSec);
                
                Ydata = downsample(ABdata,nthSample);
                Xdata = downsample(indices/app.Metadata.SamplingFrequency, nthSample);
              
                plot(app.UIAxes2, Xdata, Ydata, ...
                    'Color', "#00ff00", 'LineWidth', 2);
                
                label = int2str(i);
                xline(app.UIAxes2, Xdata(1), ':', label);
            end
            
            hold(app.UIAxes2, 'off');
            
            
        end
        
        function setABAxes(app, xmin, xmax)
            app.UIAxes2.XLim = [xmin, xmax];
            app.GoToEditField.Value = xmin;

        
        end
        
        function setCHAxes(app, xmin, xmax)
            app.UIAxes.XLim = [xmin, xmax];
            app.GoToEditField.Value = xmin;

        end
        
        function resetAxes(app)
            app.Width = 30;
             app.UIAxes.XLim = [0, app.Width];
             app.UIAxes2.XLim = [0, app.Width];
        end
        
        function updateSummary(app)
            inds = app.CHData.getIndicesAnalyzable();
            if ~isempty(app.RemovedBreaths)
                inds(app.RemovedBreaths) = [];
            end
            
            app.TotalNumberofBreathsLabel.Text = ['Total Number of Breaths: ' num2str(app.CHData.getNumBreaths())];
            app.NumberofAnalyzableBreathsLabel.Text = ['Number of Analyzable Breaths: ' num2str(length(inds))...
                ' (' num2str(length(inds)/app.CHData.getNumBreaths()*100, 2) '%)'];  
            
            [meanPA, sdPA] = app.CHData.calculateMeanPA(inds);
            [meanTTE, sdTTE] = app.CHData.calculateMeanTPEFTE(inds);
            [meanRC, sdRC] = app.CHData.calculateMeanRCI(inds);
            [meanRR, sdRR] = app.CHData.calculateMeanRespiratoryRate(inds);
            
            app.MeanPALabel.Text = ['Mean PA (deg): ' num2str(meanPA, 4) ' (SD: ' num2str(sdPA,4) ')'];
            app.MeanTPEFTELabel.Text = ['Mean Tpef/TE: ' num2str(meanTTE, 3) ' (SD: ' num2str(sdTTE,3) ')'];
            app.MeanRCiLabel.Text = ['Mean %RCi: ' num2str(meanRC, 3) ' (SD: ' num2str(sdRC,2) ')'];
            app.MeanDurationLabel.Text = ['Mean Respiratory Rate (bpm): ' num2str(meanRR,3) ' (SD: ' num2str(sdRR,2) ')'];
                      
        end
        
        function adjustComponents(app)
            app.BreathEditField.Limits = [0, length(app.CHData.getIndicesAnalyzable())];
            
            
            app.GoToEditField.Value = 0;
            app.DisplayEditField.Value = 30;
        end
        
        
        function updatePerBreathAnalysis(app, breathNum)
            breath = app.CHData.getBreath(breathNum);
            if breath.getAnalyzable()
                app.PALabel.Text = ['PA (deg): ' num2str(breath.getPhaseAngle(), 4)];
                app.TPEFTELabel.Text = ['Tpef/TE: ' num2str(breath.getTPEFTE(), 3)];
                app.RCiLabel.Text = ['%RCi: ' num2str(breath.getRCI(), 3)];
                app.DurationLabel.Text = ['Duration (sec): ' num2str(breath.getDuration(),3)];
                
            else
                warning('Non-analyzable breath index passed to app.updatePerBreathAnalysis')
                app.PALabel.Text = ['PA (deg): NA'];
                app.TPEFTELabel.Text = ['Tpef/TE: NA'];
                app.RCiLabel.Text = ['%RCi: NA'];
                app.DurationLabel.Text = ['Duration (sec): NA'];
            end
            
        end
        
        function setExcludeButton(app)
            if any(app.RemovedBreaths == app.CurrentBreath) % breath already excluded
                app.ExcludeBreathButton.BackgroundColor = [0.76,0.93,0.54];
                app.ExcludeBreathButton.Text = "Include Breath";
            else
                app.ExcludeBreathButton.BackgroundColor = [0.93,0.69,0.13];
                app.ExcludeBreathButton.Text = "Exclude Breath";
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CHData)

            app.OriginalCHData = CHData;
            app.CHData = CHData;
 
            app.Metadata = CHData.getMetadata();
            app.adjustComponents();
            
            app.plotBreaths();
            app.updateSummary();
        end

        % Button pushed function: ExportAnalysisButton
        function ExportAnalysisButtonPushed(app, event)

            tempCHData = app.CHData; % the export process alters analyzable breaths based on user input
            fileMaker = FileIOHandler(app.CHData);
            fileMaker.exportAnalysis(app.UIFigure, app.RemovedBreaths);
            app.CHData = tempCHData;
            
        end

        % Button pushed function: ViewAnalysisDetailsButton
        function ViewAnalysisDetailsButtonPushed(app, event)
            message = sprintf([app.Metadata.SleepStatusReport app.Metadata.QDCReport]);
            uialert(app.UIFigure, message, 'Analysis Details','Icon','none');
        end

        % Value changed function: BreathEditField
        function BreathEditFieldValueChanged(app, event)
            app.CurrentBreath = app.BreathEditField.Value;
            analyzableBreaths = app.CHData.getIndicesAnalyzable();
            nthGoodBreath = analyzableBreaths(app.CurrentBreath);
            CHbreath = app.CHData.getBreath(nthGoodBreath);
            sInd = CHbreath.getStartIndex()/app.Metadata.SamplingFrequency;
            app.setCHAxes(sInd, sInd + app.Width);
            app.setABAxes(sInd, sInd + app.Width);
            
            app.updatePerBreathAnalysis(analyzableBreaths(app.CurrentBreath));
            
            app.setExcludeButton();
        end

        % Value changed function: GoToEditField
        function GoToEditFieldValueChanged(app, event)
            value = app.GoToEditField.Value;
            
            app.setCHAxes(value, value+app.Width);
            app.setABAxes(value, value+app.Width);
            
        end

        % Value changed function: DisplayEditField
        function DisplayEditFieldValueChanged(app, event)
            app.Width = app.DisplayEditField.Value;
            startValue = app.GoToEditField.Value;
            app.setCHAxes(startValue, startValue + app.Width);
            app.setABAxes(startValue, startValue + app.Width);
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % FORWARD BUTTON
            
            analyzableInds = app.CHData.getIndicesAnalyzable();
            if (app.CurrentBreath + 1) > length(analyzableInds)
                app.CurrentBreath = 1;
            else
                app.CurrentBreath = app.CurrentBreath + 1;
            end
       
            nextBreath = app.CHData.getBreath(analyzableInds(app.CurrentBreath));
            startIndex = nextBreath.getStartIndex();
            startIndex = startIndex/app.Metadata.SamplingFrequency;
        
            app.BreathEditField.Value = app.CurrentBreath;
            
            app.setCHAxes(startIndex, startIndex + app.Width);
            app.setABAxes(startIndex, startIndex + app.Width);
            
            app.updatePerBreathAnalysis(analyzableInds(app.CurrentBreath));
            
            app.setExcludeButton();


        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % BACK BUTTON

            analyzableInds = app.CHData.getIndicesAnalyzable();
            if (app.CurrentBreath - 1) < 1
                app.CurrentBreath = length(analyzableInds);
            else
                app.CurrentBreath = app.CurrentBreath - 1;
            end
            
            prevBreath = app.CHData.getBreath(analyzableInds(app.CurrentBreath));
            startIndex = prevBreath.getStartIndex()/app.Metadata.SamplingFrequency;
            
            app.BreathEditField.Value = app.CurrentBreath;

            app.setCHAxes(startIndex, startIndex + app.Width);
            app.setABAxes(startIndex, startIndex + app.Width);
            
            app.updatePerBreathAnalysis(analyzableInds(app.CurrentBreath));
            
            app.setExcludeButton();
           
        end

        % Button pushed function: ResetAxesButton
        function ResetAxesButtonPushed(app, event)
            app.Width = 30;
            app.setCHAxes(0,app.Width);
            app.setABAxes(0,app.Width);
            app.BreathEditField.Value = 1;
            app.GoToEditField.Value = 0;
            app.DisplayEditField.Value = 30;
        end

        % Button pushed function: ViewKMPlotButton
        function ViewKMPlotButtonPushed(app, event)
            value = app.BreathEditField.Value;
            analyzableBreaths = app.CHData.getIndicesAnalyzable();
            CHnum = analyzableBreaths(value);
            CHbreath = app.CHData.getBreath(CHnum);
            CHdata = CHbreath.getData();
            ABdata = CHbreath.getAssociatedData();
            f = gcf;
            set(f, 'NumberTitle', 'off', ...
            'Name', ['Konno-Mead Plot: Breath ', num2str(analyzableBreaths(value))]);
            plot(ABdata, CHdata);
            title(['Konno-Mead Plot, Breath ' num2str(value)]);
            xlabel('AB Data');
            ylabel('CH Data');
        end

        % Button pushed function: ExcludeBreathButton
        function ExcludeBreathButtonPushed(app, event)
            value = app.CurrentBreath;
            
            analyzableBreaths = app.CHData.getIndicesAnalyzable();
            breath = app.CHData.getBreath(analyzableBreaths(value));
            indices = breath.getIndices()/app.Metadata.SamplingFrequency;
                            
            hold(app.UIAxes, 'on');
            hold(app.UIAxes2, 'on');
            
            if any(app.RemovedBreaths == value) % re-including a breath
                app.RemovedBreaths(app.RemovedBreaths == value) = [];

                plot(app.UIAxes, indices, breath.getData(), 'Color', '#00ff00', 'LineWidth', 2);
                plot(app.UIAxes2, indices, breath.getAssociatedData(), 'Color', '#00ff00', 'LineWidth', 2);
                
            else % excluding a breath
                app.RemovedBreaths = [app.RemovedBreaths; value];
                
                plot(app.UIAxes, indices, breath.getData(), 'Color', '#ff0000', 'LineWidth', 2);
                plot(app.UIAxes2, indices, breath.getAssociatedData(), 'Color', '#ff0000', 'LineWidth', 2);
                
            end
            
            app.updateSummary();
            app.setExcludeButton();
        end

        % Button pushed function: ResetAnalysisButton
        function ResetAnalysisButtonPushed(app, event)
            app.CHData = app.OriginalCHData;
            app.RemovedBreaths = [];
            app.plotBreaths();
            app.BreathEditField.Value = 0;
            app.CurrentBreath = 0;
            app.updateSummary();
        end

        % Button pushed function: ExportRawDataButton
        function ExportRawDataButtonPushed(app, event)
            fileMaker = FileIOHandler(app.CHData);
            fileMaker.saveRawDataFile(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.AxisControlsPanel = uipanel(app.GridLayout);
            app.AxisControlsPanel.Title = 'Axis Controls';
            app.GoToEditFieldLabel = uilabel(app.AxisControlsPanel);
            app.GoToEditFieldLabel.Text = 'Go To';
            app.GoToEditField = uieditfield(app.AxisControlsPanel, 'numeric');
            app.GoToEditField.ValueDisplayFormat = '%.1f';
            app.GoToEditField.ValueChangedFcn = createCallbackFcn(app, @GoToEditFieldValueChanged, true);
            app.DisplayEditFieldLabel = uilabel(app.AxisControlsPanel);
            app.DisplayEditFieldLabel.Text = 'Display';
            app.DisplayEditField = uieditfield(app.AxisControlsPanel, 'numeric');
            app.DisplayEditField.ValueDisplayFormat = '%.1f';
            app.DisplayEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayEditFieldValueChanged, true);
            app.ResetAxesButton = uibutton(app.AxisControlsPanel, 'push');
            app.ResetAxesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAxesButtonPushed, true);
            app.ResetAxesButton.Text = 'Reset Axes';
            app.secondsofdataLabel = uilabel(app.AxisControlsPanel);
            app.secondsofdataLabel.Text = 'seconds of data';
            app.secondsLabel = uilabel(app.AxisControlsPanel);
            app.secondsLabel.Text = 'seconds';
            app.PerBreathAnalysisPanel = uipanel(app.GridLayout);
            app.PerBreathAnalysisPanel.Title = 'Per-Breath Analysis';
            app.ExcludeBreathButton = uibutton(app.PerBreathAnalysisPanel, 'push');
            app.ExcludeBreathButton.ButtonPushedFcn = createCallbackFcn(app, @ExcludeBreathButtonPushed, true);
            app.ExcludeBreathButton.Text = 'Exclude Breath';
            app.PALabel = uilabel(app.PerBreathAnalysisPanel);
            app.PALabel.Text = 'PA:';
            app.TPEFTELabel = uilabel(app.PerBreathAnalysisPanel);
            app.TPEFTELabel.Text = 'TPEF/TE:';
            app.RCiLabel = uilabel(app.PerBreathAnalysisPanel);
            app.RCiLabel.Text = '%RCi: ';
            app.ViewKMPlotButton = uibutton(app.PerBreathAnalysisPanel, 'push');
            app.ViewKMPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ViewKMPlotButtonPushed, true);
            app.ViewKMPlotButton.Text = 'View KM Plot';
            app.DurationLabel = uilabel(app.PerBreathAnalysisPanel);
            app.DurationLabel.Text = 'Duration:';
            app.ResetAnalysisButton = uibutton(app.GridLayout, 'push');
            app.ResetAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAnalysisButtonPushed, true);
            app.ResetAnalysisButton.Text = 'Reset Analysis';
            app.ViewAnalysisDetailsButton = uibutton(app.GridLayout, 'push');
            app.ViewAnalysisDetailsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewAnalysisDetailsButtonPushed, true);
            app.ViewAnalysisDetailsButton.Text = 'View Analysis Details';
            app.ExportAnalysisButton = uibutton(app.GridLayout, 'push');
            app.ExportAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ExportAnalysisButtonPushed, true);
            app.ExportAnalysisButton.Text = 'Export Analysis';
            app.SummaryofRecordingPanel = uipanel(app.GridLayout);
            app.SummaryofRecordingPanel.Title = 'Summary of Recording';
            app.MeanPALabel = uilabel(app.SummaryofRecordingPanel);
            app.MeanPALabel.Text = 'Mean PA:';
            app.TotalNumberofBreathsLabel = uilabel(app.SummaryofRecordingPanel);
            app.TotalNumberofBreathsLabel.Text = 'Total Number of Breaths:';
            app.MeanTPEFTELabel = uilabel(app.SummaryofRecordingPanel);
            app.MeanTPEFTELabel.Text = 'Mean TPEF/TE:';
            app.MeanRCiLabel = uilabel(app.SummaryofRecordingPanel);
            app.MeanRCiLabel.Text = 'Mean %RCi: ';
            app.NumberofAnalyzableBreathsLabel = uilabel(app.SummaryofRecordingPanel);
            app.NumberofAnalyzableBreathsLabel.Text = 'Number of Analyzable Breaths:';
            app.MeanDurationLabel = uilabel(app.SummaryofRecordingPanel);
            app.MeanDurationLabel.Text = 'Mean Duration:';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'CH Data')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'CH Signal (mV)')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'AB Data')
            xlabel(app.UIAxes2, 'Time (sec)')
            ylabel(app.UIAxes2, 'AB Signal (mV)')
            app.SelectBreathPanel = uipanel(app.GridLayout);
            app.SelectBreathPanel.Title = 'Select Breath';
            app.BreathEditFieldLabel = uilabel(app.SelectBreathPanel);
            app.BreathEditFieldLabel.Text = 'Breath';
            app.BreathEditField = uieditfield(app.SelectBreathPanel, 'numeric');
            app.BreathEditField.Limits = [0 1];
            app.BreathEditField.ValueChangedFcn = createCallbackFcn(app, @BreathEditFieldValueChanged, true);
            app.Button = uibutton(app.SelectBreathPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '>';
            app.Button_2 = uibutton(app.SelectBreathPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '<';
            app.PreviousNextAnalyzableBreathLabel = uilabel(app.SelectBreathPanel);
            app.PreviousNextAnalyzableBreathLabel.Text = {'Previous/Next '; 'Analyzable Breath'};
            app.ExportRawDataButton = uibutton(app.GridLayout, 'push');
            app.ExportRawDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportRawDataButtonPushed, true);
            app.ExportRawDataButton.Text = 'Export Raw Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysisScreen2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end